classdef PlannerMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        LoginMenu             matlab.ui.container.Menu
        LogoutMenu            matlab.ui.container.Menu
        Menu                  matlab.ui.container.Menu
        NewMenu               matlab.ui.container.Menu
        OpenMenu              matlab.ui.container.Menu
        SaveMenu              matlab.ui.container.Menu
        CloseMenu             matlab.ui.container.Menu
        PlanMenu              matlab.ui.container.Menu
        ParametersMenu        matlab.ui.container.Menu
        UniqueTargetsMenu     matlab.ui.container.Menu
        ValidateMenu          matlab.ui.container.Menu
        SubmitMenu            matlab.ui.container.Menu
        ViewMenu              matlab.ui.container.Menu
        TabGroup              matlab.ui.container.TabGroup
        HCSTab                matlab.ui.container.Tab
        EditField             matlab.ui.control.NumericEditField
        EditFieldLabel        matlab.ui.control.Label
        LCSTab                matlab.ui.container.Tab
        EditField_2           matlab.ui.control.NumericEditField
        EditField_2Label      matlab.ui.control.Label
        DDTTab                matlab.ui.container.Tab
        AllSkyTab             matlab.ui.container.Tab
        TOOTab                matlab.ui.container.Tab
        PlotGraphsPanel       matlab.ui.container.Panel
        PlotSkyMapPanel       matlab.ui.container.Panel
        ApprovedTargetsPanel  matlab.ui.container.Panel
        UITable               matlab.ui.control.Table
        PlanPanel             matlab.ui.container.Panel
        UniqueTargetsPanel    matlab.ui.container.Panel
        PlanParamsPanel       matlab.ui.container.Panel
        Panel                 matlab.ui.container.Panel
        BuildButton           matlab.ui.control.Button
        LoginButton           matlab.ui.control.Button
        Debug1Button          matlab.ui.control.Button
        UniqueButton          matlab.ui.control.Button
        GetApprovedButton     matlab.ui.control.Button
        ParamsButton          matlab.ui.control.Button
        ValidateButton        matlab.ui.control.Button
        SubmitButton          matlab.ui.control.Button
        SaveButton            matlab.ui.control.Button
        OpenButton            matlab.ui.control.Button
        NewButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Data
        MainModule 

        % Forms (AppDesigner Apps in ultrasat.planner.gui)
        NewPlanApp              % Description
        OpenPlanApp             %
        UniqueTargetsApp        % 
        PlanParamsApp           %
        ValidateApp             % 
        SubmitApp               % ?
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    
    % =====================================================================

    methods (Access = public)
        
        function login(app)
            LoginApp = ultrasat.planner.gui.Login(app.MainModule);
            uiwait(LoginApp.UIFigure);            
        end


        function logout(app)
            %
            app.DataModule.logout();
        end        


        function createNewPlan(app)
    
            app.closePlan();

            %
            if isempty(app.NewPlanApp) || ~isvalid(app.NewPlanApp)
                app.NewPlanApp = ultrasat.planner.gui.NewPlan(app.MainModule);                
            end
            uiwait(app.NewPlanApp);
            %app.NewPlanApp.UIFigure.Visible = 'on';            

            if strcmp(app.NewPlanApp.Status, 'Create')
                AppUtils.msgOk('Create new: %s ....', app.DataModule.PlanType);
                try
                    app.doCreateNewPlan();
                catch ME
                    AppUtils.msgError(ME.message, 'create: Exception');
                end

            else
                AppUtils.msgOk('Create new canceled');
                return;
            end
        end


        function doCreateNewPlan(app)

            if strcmp(app.DataModule.PlanType, 'HCS')
                HCS_fields = table({'S1','N2','N3'}',[67,215,254]',[-59,60,64]','VariableNames',{'Name','RA','Dec'},'RowNames',{'S1','N2','N3'}');

                upHCS = ultrasat.planner.uplanner('AstPlanner','YS','Type','HCS');
                app.DataModule.Planner = upHCS;
                app.DataModule.PlanType = 'HCS';

                upHCS.StartTime = '2028-01-01 12:00:00';  % app.NewPlanApp.StarttimeEditField.Value;
                upHCS.EndTime = '2028-07-01 12:00:00';  % app.NewPlanApp.EndtimeEditField.Value;
                upHCS.addUniqTargets(HCS_fields.RA('S1'), HCS_fields.Dec('S1'),' Name',H CS_fields.Name('S1'));
                upHCS.buildHCS;
                save('C:/AstroPack/data/ULTRASAT/upHCS.mat', 'upHCS');            
            end


            if strcmp(app.DataModule.PlanType, 'LCS')
            end

            if strcmp(app.DataModule.PlanType, 'DDT')
            end

            if strcmp(app.DataModule.PlanType, 'AllSS')
            end

            if strcmp(app.DataModule.PlanType, 'TOO')
            end            
        end


        function closePlan(app)
        end

        % =================================================================

        function build(app)
            if isempty(app.DataModule.Planner)
                return;
            end

            try
                if strcmp(app.DataModule.PlanType, 'HCS')
                    app.DataModule.Planner.buildHCS();
                end
    
                if strcmp(app.DataModule.PlanType, 'LCS')
                    app.DataModule.Planner.buildLCS();
                end
    
                if strcmp(app.DataModule.PlanType, 'DDT')
                end
    
                if strcmp(app.DataModule.PlanType, 'AllSS')
                    app.DataModule.Planner.buildAllSS();
                end
    
                if strcmp(app.DataModule.PlanType, 'TOO')
                    app.DataModule.Planner.buildTOO();
                end            
            catch ME
                AppUtils.msgError(ME.message, 'build: Exception');
            end
        end


        end


        function validate(app)
        end        


        function submit(app)
        end        


        function getApproved(app)
        end                

        % =================================================================

        function results = msglog(app, s)
            app.MainModule.msglog(s);            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MainModule = ultrasat.planner.gui.MainModule();
            app.MainModule.MainApp = app;
            app.msglog('started');
            
        end

        % Callback function: not associated with a component
        function NewButtonPushed(app, event)
            
        end

        % Button pushed function: UniqueButton
        function UniqueButtonPushed(app, event)
            %

        end

        % Callback function: LoginButton, LoginMenu
        function LoginMenuSelected(app, event)
            % Login
            app.login();
        end

        % Menu selected function: LogoutMenu
        function LogoutMenuSelected(app, event)
            % Logout
        end

        % Callback function: NewButton, NewMenu
        function NewMenuSelected(app, event)
            app.createNewPlan();
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            %
            %
            if isempty(app.UniqueTargetsApp) || ~isvalid(app.UniqueTargetsApp)
                app.UniqueTargetsApp = ultrasat.planner.gui.OpenPlan();
                app.UniqueTargetsApp.MainApp = app;
            end
            app.UniqueTargetsApp.UIFigure.Visible = 'on';                                    
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            %
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            %
            answer = questdlg('Are you sure you want to exit?', 'Confirm action', 'Yes', 'No', 'No');
            if strcmpi(answer, 'Yes')
                app.delete();
            else
                % Just do nothing
            end
        end

        % Menu selected function: UniqueTargetsMenu
        function UniqueTargetsMenuSelected(app, event)
            if isempty(app.UniqueTargetsApp) || ~isvalid(app.UniqueTargetsApp)
                app.UniqueTargetsApp = ultrasat.planner.gui.UniqueTargets();
                app.UniqueTargetsApp.MainApp = app;
            end
            app.UniqueTargetsApp.UIFigure.Visible = 'on';                                    
        end

        % Menu selected function: ParametersMenu
        function ParametersMenuSelected(app, event)
            %
            if isempty(app.PlanParamsApp) || ~isvalid(app.PlanParamsApp)
                app.PlanParamsApp = ultrasat.planner.gui.PlanParams();
                app.PlanParamsApp.MainApp = app;
            end
            app.PlanParamsApp.UIFigure.Visible = 'on';                                                
        end

        % Menu selected function: ValidateMenu
        function ValidateMenuSelected(app, event)
            %
        end

        % Menu selected function: SubmitMenu
        function SubmitMenuSelected(app, event)
            %
        end

        % Size changed function: ApprovedTargetsPanel
        function ApprovedTargetsPanelSizeChanged(app, event)
            % position = app.ApprovedTargetsPanel.Position;
            %
        end

        % Size changed function: PlanPanel
        function PlanPanelSizeChanged(app, event)
            % position = app.PlanPanel.Position;
            %
        end

        % Size changed function: UniqueTargetsPanel
        function UniqueTargetsPanelSizeChanged(app, event)
            % position = app.UniqueTargetsPanel.Position;
            % 
        end

        % Button pushed function: Debug1Button
        function Debug1ButtonPushed(app, event)
            %
            app.planner = ultrasat.planner.uplanner();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoginMenu = uimenu(app.FileMenu);
            app.LoginMenu.MenuSelectedFcn = createCallbackFcn(app, @LoginMenuSelected, true);
            app.LoginMenu.Text = 'Login';
            app.LogoutMenu = uimenu(app.FileMenu);
            app.LogoutMenu.MenuSelectedFcn = createCallbackFcn(app, @LogoutMenuSelected, true);
            app.LogoutMenu.Text = 'Logout';
            app.Menu = uimenu(app.FileMenu);
            app.Menu.Text = '---------';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.PlanMenu = uimenu(app.UIFigure);
            app.PlanMenu.Text = 'Plan';
            app.ParametersMenu = uimenu(app.PlanMenu);
            app.ParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ParametersMenuSelected, true);
            app.ParametersMenu.Text = 'Parameters';
            app.UniqueTargetsMenu = uimenu(app.PlanMenu);
            app.UniqueTargetsMenu.MenuSelectedFcn = createCallbackFcn(app, @UniqueTargetsMenuSelected, true);
            app.UniqueTargetsMenu.Text = 'Unique Targets';
            app.ValidateMenu = uimenu(app.PlanMenu);
            app.ValidateMenu.MenuSelectedFcn = createCallbackFcn(app, @ValidateMenuSelected, true);
            app.ValidateMenu.Text = 'Validate';
            app.SubmitMenu = uimenu(app.PlanMenu);
            app.SubmitMenu.MenuSelectedFcn = createCallbackFcn(app, @SubmitMenuSelected, true);
            app.SubmitMenu.Text = 'Submit';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.Panel = uipanel(app.UIFigure);
            app.NewButton = uibutton(app.Panel, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewButton.Text = 'New';
            app.OpenButton = uibutton(app.Panel, 'push');
            app.OpenButton.Text = 'Open';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.Text = 'Save';
            app.SubmitButton = uibutton(app.Panel, 'push');
            app.SubmitButton.Text = 'Submit';
            app.ValidateButton = uibutton(app.Panel, 'push');
            app.ValidateButton.Text = 'Validate';
            app.ParamsButton = uibutton(app.Panel, 'push');
            app.ParamsButton.Text = 'Params';
            app.GetApprovedButton = uibutton(app.Panel, 'push');
            app.GetApprovedButton.Text = 'Get Approved';
            app.UniqueButton = uibutton(app.Panel, 'push');
            app.UniqueButton.ButtonPushedFcn = createCallbackFcn(app, @UniqueButtonPushed, true);
            app.UniqueButton.Text = 'Unique';
            app.Debug1Button = uibutton(app.Panel, 'push');
            app.Debug1Button.ButtonPushedFcn = createCallbackFcn(app, @Debug1ButtonPushed, true);
            app.Debug1Button.Text = 'Debug1';
            app.LoginButton = uibutton(app.Panel, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginMenuSelected, true);
            app.LoginButton.Text = 'Login';
            app.BuildButton = uibutton(app.Panel, 'push');
            app.BuildButton.Text = 'Build';
            app.PlanParamsPanel = uipanel(app.UIFigure);
            app.PlanParamsPanel.Title = 'Plan Params';
            app.UniqueTargetsPanel = uipanel(app.UIFigure);
            app.UniqueTargetsPanel.Title = 'Unique Targets';
            app.UniqueTargetsPanel.SizeChangedFcn = createCallbackFcn(app, @UniqueTargetsPanelSizeChanged, true);
            app.PlanPanel = uipanel(app.UIFigure);
            app.PlanPanel.Title = 'Plan';
            app.PlanPanel.SizeChangedFcn = createCallbackFcn(app, @PlanPanelSizeChanged, true);
            app.ApprovedTargetsPanel = uipanel(app.UIFigure);
            app.ApprovedTargetsPanel.Title = 'Approved Targets';
            app.ApprovedTargetsPanel.SizeChangedFcn = createCallbackFcn(app, @ApprovedTargetsPanelSizeChanged, true);
            app.UITable = uitable(app.ApprovedTargetsPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.PlotSkyMapPanel = uipanel(app.UIFigure);
            app.PlotSkyMapPanel.Title = 'Plot - Sky Map';
            app.PlotGraphsPanel = uipanel(app.UIFigure);
            app.PlotGraphsPanel.Title = 'Plot - Graphs';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HCSTab = uitab(app.TabGroup);
            app.HCSTab.Title = 'HCS';
            app.EditFieldLabel = uilabel(app.HCSTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.HCSTab, 'numeric');
            app.LCSTab = uitab(app.TabGroup);
            app.LCSTab.Title = 'LCS';
            app.EditField_2Label = uilabel(app.LCSTab);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.LCSTab, 'numeric');
            app.DDTTab = uitab(app.TabGroup);
            app.DDTTab.Title = 'DDT';
            app.AllSkyTab = uitab(app.TabGroup);
            app.AllSkyTab.Title = 'AllSky';
            app.TOOTab = uitab(app.TabGroup);
            app.TOOTab.Title = 'TOO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlannerMain

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end